classdef app_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        openimagebutton     matlab.ui.control.Button
        text4               matlab.ui.control.Label
        rundetectionbutton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
        axes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputimage % the input image loaded from the explorer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openimagebutton
        function openimagebuttonButtonPushed(app, event)
           
            app.text4.Text ='Processing...';
            [filename, path] = uigetfile({'*.jpg'; '*.png'}, 'File Selector', '..\dataset\6MP');           % save selected image path
            if (path == 0)
                return
            end

            fullpath = strcat(path, filename);                             % concatenate path- with filename
            app.inputimage =  imread(fullpath);                            % read the image from file
            app.text4.Text ='Ready!';
            app.rundetectionbutton.Enable = true;
            imshow(app.inputimage, 'Parent', app.axes);                    % plot the image
            
        end

        % Button pushed function: rundetectionbutton
        function rundetectionbuttonButtonPushed(app, event)
            
            app.openimagebutton.Enable = false;
            app.rundetectionbutton.Enable = false;

            main(app.inputimage);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'APR_GUI';
            app.figure1.Tag = 'figure1';
            app.axes = uiaxes(app.figure1);
            app.axes.Tag = 'axes';
            app.axes.Visible = 'off';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = '';
            app.rundetectionbutton = uibutton(app.figure1, 'push');
            app.rundetectionbutton.ButtonPushedFcn = createCallbackFcn(app, @rundetectionbuttonButtonPushed, true);
            app.rundetectionbutton.Tag = 'rundetectioncallback';
            app.rundetectionbutton.Enable = 'off';
            app.rundetectionbutton.Text = 'Run detection';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = '';
            app.openimagebutton = uibutton(app.figure1, 'push');
            app.openimagebutton.ButtonPushedFcn = createCallbackFcn(app, @openimagebuttonButtonPushed, true);
            app.openimagebutton.Tag = 'openimagecallback';
            app.openimagebutton.Text = 'Open Image';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end